classdef dataVisiualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure  matlab.ui.Figure
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        covidData={}, % Description
        ShowCases,
        ShowDeaths,
        ShowBoth,
        ShowCumulative,
        ShowDaily,
        WindowLength,
        CountryList,
        CumulativeCase,
        CumulativeDeath,
        Date,
        DailyDeath,
        DailyCase,
        CountryName,
        StateName
        
        
    end
    
    methods (Access = private)
        
        function insertCountry(app,countryObj,countryName, posInformation,idx)
            
            if ~isstring(posInformation) || isempty(posInformation)
                error("Position information is not valid");
            end
            
            countryObj.setCountryName(countryName);
            
            if strcmp(posInformation,"Prepend")
                app.CountryList= [countryObj app.CountryList];
            elseif strcmp(posInformation,"Append")
                app.CountryList= [app.CountryList countryObj];
            else
                app.CountryList=[app.CountryList(1:idx-1) countryObj app.CountryList(idx:end)];
            end


        end
        function aData=computeAverage(app,data)
            
            % Take average of data
            aData=movmean(data,app.WindowLength);
            
        end
        
        function populateCountries(app)
            
            % Get row size of data
            nRow = size(app.covidData,1);
            
            % Pre-define country nmae
            previousCountryName= " ";
            
            % Pre-define country list
            app.CountryList=[];
            
            % Pre-define golabal data
            globalData={};
            
            % Get date information
            date=app.covidData(1,3:end);
            
            for ii=2:nRow
                
                % Get country name
                currentCountryName= app.covidData{ii,1};
                
                % Get state name
                stateName =app.covidData{ii,2};
                
                % Get Data
                cData= app.covidData(ii,3:end);
                
                
                if ~strcmp(previousCountryName,currentCountryName)
                    previousCountryName=currentCountryName;
                    cObj=Country();
                    insertCountry(app,cObj,currentCountryName,"Append");
                end
                
                % Populate state according to the country
                cObj.populateStates(stateName,cData,date);
                
                if isempty(globalData)
                    globalData=cData;
                else
                    % Calculate cumulative data for global
                    globalData = cellfun(@(x,y)x+y,cData,globalData,'UniformOutput',false);
                end
            end
            
            % insert global country
            glo=Country();
            insertCountry(app,glo,'Global',"Prepend");
            glo.populateStates(stateName,globalData,date);
            
            
        end
        
        function assignCountryList(app)
            
            cNames={};
            for ii = 1: length(app.CountryList)
                
                name=app.CountryList(ii).getName;
                
                cNames= [cNames, {name}];
            end
            % Now fill the list box with populated elements
            app.CountryListBox.Items=cNames;
            
            % Set current item which is global for starting-up of app.
            app.CountryListBox.Value=cNames(1);
            
        end
        
        function stateName=getStateName(app,countryName)
            % This method return the states or state name according to country name
            
            for idx =1: length(app.CountryList)
                cName = app.CountryList(idx).getName;
                
                if strcmp(cName,countryName)
                    stateName= app.CountryList(idx).getStatesName;
                    break;
                end
            end
            
        end
        
        function stateList = getStateList(app,countryName)
            
            % This method return the state list of  country
            for idx =1: length(app.CountryList)
                cName = app.CountryList(idx).getName;
                
                if strcmp(cName,countryName)
                    stateList= app.CountryList(idx).getStateList;
                    break;
                end
            end
        end
        
        function state = getState(app,stateList,stateName)
            % This method return the state list of  country
            
            for idx =1: length(stateList)
                sName = stateList(idx).getName;
                
                if strcmp(sName,stateName)
                    state= stateList(idx);
                    break;
                end
            end
            
        end
        function assignCurrentProperties(app)
            
            % Get selected state name
            app.StateName = app.StateorRegionListBox.Value;
            
            % Get country name of selected state
            app.CountryName=app.CountryListBox.Value;
            
            % Get state list
            stateList=app.getStateList(app.CountryName);
            
            % Get state
            state = app.getState(stateList,app.StateName);
            
            % Get date info
            app.Date=state.getDate();
            
            % Get cumulative case data
            app.CumulativeCase=state.getCumulativeCase();
            
            % Get cumulative death data
            app.CumulativeDeath= state.getCumulativeDeath();
            
            % Get daily case data
            app.DailyCase= state.getDailyCase();
            
            % Get daily death data
            app.DailyDeath= state.getDailyDeath();
            
        end
        
        function plotData(app)
            
            % Define staring date
            startDate=app.Date(1);
            
            % Define ending date
            endDate=app.Date(end);
            
            % Convert date to num
            fDate=datenum(startDate);
            lDate=datenum(endDate);
            
            % Create an date array in numeric values
            xDate=fDate:lDate;
            
            % Clear all axes
            cla(app.UIAxes,'reset');
                        
            fTitle="";
            if app.ShowCases && ~app.ShowBoth
                
                if app.ShowCumulative
                    
                    % take the average according the current value of slider
                    aCase=app.computeAverage(app.CumulativeCase);
                    fTitle="Cumulative Number of ";
                elseif app.ShowDaily
                    % take the average according the current value of slider
                    
                    aCase=app.computeAverage(app.DailyCase);
                    fTitle="Daily Number of ";
                end
                
                % plot cumulative case data
                bar(app.UIAxes,xDate,aCase);


                % Set title of figure
                fTitle= fTitle + "Cases in ";
            elseif app.ShowDeaths && ~app.ShowBoth
                
                if app.ShowCumulative
                    % take the average according the current value of slider
                    aDeath=app.computeAverage(app.CumulativeDeath);
                    fTitle="Cumulative Number of ";
                    
                elseif app.ShowDaily
                    % take the average according the current value of slider
                    aDeath=app.computeAverage(app.DailyDeath);
                    fTitle="Daily Number of ";
                    
                end
                
                % plot  death data
                plot(app.UIAxes,xDate,aDeath,'r-','LineWidth',2);
                
                
                % Set title of figure
                fTitle= fTitle + "Deaths in ";
                
                
            else
                
                if app.ShowCumulative
                    
                    aDeath=app.computeAverage(app.CumulativeDeath);
                    aCase=app.computeAverage(app.CumulativeCase);
                    fTitle="Cumulative Number of ";
                    
                elseif app.ShowDaily
                    
                    % take the average according the current value of slider
                    aDeath=app.computeAverage(app.DailyDeath);
                    aCase=app.computeAverage(app.DailyCase);
                    fTitle="Daily Number of ";
                    
                end
                
                % For both show case, use two axes
                yyaxis(app.UIAxes,'left');
                
                % plot  case data
                bar(app.UIAxes,xDate,aCase);
                
                % hold on figure
                hold(app.UIAxes,'on');
                
                % For both show case, use two axes
                yyaxis(app.UIAxes,'right');
                
                % plot  death data
                plot(app.UIAxes,xDate,aDeath,'r-','LineWidth',2);
                
                fTitle= fTitle +"Cases and Deaths in ";
                

            end
            
            
             if ~strcmp(app.StateName,'All')
                 fTitle =fTitle+app.StateName;
             else
                 fTitle =fTitle+app.CountryName;
             end
             
             if app.WindowLength ~=1
                 fTitle = fTitle +" ("+app.WindowLength+"-day mean)";
             end
            grid(app.UIAxes,'on');
            title(app.UIAxes,fTitle);
            datetick(app.UIAxes,'x','mm/dd/yyyy','keeplimits');
            
            hold(app.UIAxes,'off');
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % First Load the data
            load covid_data.mat covid_data;
            
            % Set data
            app.covidData =covid_data;
            
            % Populate the vector of country object
            populateCountries(app);
            
            % Assign country list
            assignCountryList(app);
            
            % Init button definations
            app.ShowCases= app.CasesButton.Value;
            app.ShowDeaths=app.DeathsButton.Value;
            app.ShowBoth=app.BothButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            app.ShowCumulative = app.CumulativeButton.Value;
            
            % Init slider
            app.WindowLength=app.AveragedofdaysSlider.Value;
                    
            
            % Assign current data properites
            app.assignCurrentProperties();
            
            % Plot data
            app.plotData();
            
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            
            app.ShowCases = app.CasesButton.Value;
            app.ShowDeaths = app.DeathsButton.Value;
            app.ShowBoth= app.BothButton.Value;
            
            
            % Plot data
            app.plotData();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            % Set button definations
            app.ShowDaily =app.DailyButton.Value;
            app.ShowCumulative = app.CumulativeButton.Value;
            
            app.plotData();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            value = app.CountryListBox.Value;
            
            % Get state list according to the selected element from country list
            states=app.getStateName(value);
            
            % set state list
            app.StateorRegionListBox.Items=states;
            
            % Country is changed. So, assigne current data
            app.assignCurrentProperties();
            
            % Plot data
            app.plotData();
            
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            
            % State is changed. Hence, assign new values
            app.assignCurrentProperties();
            
            % Plot data
            app.plotData();
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            
            % Set current window length
            app.WindowLength = uint8(event.Value);
            
            % Plot data
            app.plotData();
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure = uifigure;
            app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure.Name = 'Covid-19 Data Visualization (Data Source: John Hopkins University)';
            app.CountryListBoxLabel = uilabel(app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorRegionListBoxLabel = uilabel(app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.StateorRegionListBoxLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.StateorRegionListBox.Items = {'All'};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = 'All';
            app.AveragedofdaysSliderLabel = uilabel(app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.AveragedofdaysSlider.Limits = [1 120];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.MinorTicks = [];
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.UIAxes = uiaxes(app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = dataVisiualization

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Covid19DataVisualizationDataSourceJohnHopkinsUniversityUIFigure)
        end
    end
end